classdef FUCCI_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        File             matlab.ui.container.Menu
        open             matlab.ui.container.Menu
        RawdatacsvMenu   matlab.ui.container.Menu
        open_classified  matlab.ui.container.Menu
        ImagefilesMenu   matlab.ui.container.Menu
        SavetofileMenu   matlab.ui.container.Menu
        listbox1         matlab.ui.control.ListBox
        Save_data        matlab.ui.control.Button
        popupmenu1       matlab.ui.control.DropDown
        AutomaticcharacterisationsettingsPanel  matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        edit1            matlab.ui.control.EditField
        edit2            matlab.ui.control.EditField
        edit3            matlab.ui.control.EditField
        edit4            matlab.ui.control.EditField
        edit5            matlab.ui.control.EditField
        text2            matlab.ui.control.Label
        text3            matlab.ui.control.Label
        text4            matlab.ui.control.Label
        text5            matlab.ui.control.Label
        text6            matlab.ui.control.Label
        ManualcharacterisationupdaterPanel  matlab.ui.container.Panel
        pushbutton4      matlab.ui.control.Button
        text8            matlab.ui.control.Label
        text7            matlab.ui.control.Label
        edit8            matlab.ui.control.EditField
        edit7            matlab.ui.control.EditField
        listbox2         matlab.ui.control.ListBox
        IntensityPlot    matlab.ui.control.UIAxes
        ImagePlot        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        phases = ["Uncertain", "M", "G1", "S", "G2"]
        phaseval = [0, 0.25, 0.5, 0.75, 1]
    end
    
    methods (Access = private)
        function green_Slope = calc_slope(app, green_channel2)
            for jj=1:length(green_channel2)-4
                green_vect = green_channel2(jj:jj+4);
                P = polyfit([1:5],green_vect,1);
                green_Slope(jj+1) = P(1);
            end
        end
        
        function is_mitosis = check_for_mitosis(app, NUM, ii, handles)
            % Check for mitosis on un-annotated data (JWB)
            aa=find(NUM(:,2)==ii);
            if ~isempty(aa)
                a0=length(NUM(aa,8));
                b0=length(unique(NUM(aa,8)));
                if a0==b0
                    is_mitosis=0;
                    set(handles.listbox1,'string','1');
                    set(handles.listbox1,'value',1)
                else
                    is_mitosis=1;
                    set(handles.listbox1,'string',{'1';'2'});
                    set(handles.listbox1,'value',1);
                end
            end
        end
        
        function is_mitosis = check_for_mitosis_an(app, annotated_data, current_track, handles)
            % Check for mitosis on annotated data (JWB)
            is_mitosis=0;
            set(handles.listbox1,'string','1');
            set(handles.listbox1,'value',1)
            a0 = find(annotated_data(:,1)==current_track);
            b0 = unique(annotated_data(a0, 2));
            if length(b0) > 1
                is_mitosis = 1;
                set(handles.listbox1,'string',{'1';'2'});
                set(handles.listbox1,'value',1);
            end
        end
        
        function classified = classify_stages(app, params, red_channel, green_channel, white_channel, red_channel2, green_channel2, white_channel2)
            % Function to classify the stages in channels. White channels are not used
            % for classification yet.
            
            % Calculate slopes
            green_Slope  = calc_slope(app, green_channel2);
            red_Slope    = calc_slope(app, red_channel2);
            white_Slope  = calc_slope(app, white_channel2);
            thresh_gradual=params.thresh_gradual;
            thresh_steep=params.thresh_steep;
            time_interval_mitosis=params.time_interval_mitosis;
            time_min_mitosis=params.time_min_mitosis;
            min_signal_drop=params.min_signal_drop;
            classified=zeros(size(green_Slope));
            % find mitosis
            a0=find(red_Slope<thresh_steep);
            classified(a0)=0.25;
            % find beginning of all mitosis events
            if ~isempty(a0)
            a00=find(diff(a0)>1);
            
            mitosis_begin=[a0(1) a0(a00+1)];
            if length(mitosis_begin)>1
                mitosis_begin2=mitosis_begin(1);
                for ii=1:length(mitosis_begin)-1
                    if mitosis_begin(ii+1)-mitosis_begin(ii)>time_interval_mitosis
                        mitosis_begin2=[mitosis_begin2 mitosis_begin(ii+1)];
                    else
                        classified(mitosis_begin(ii):mitosis_begin(ii+1))=0.25;
                    end
                end
                    mitosis_begin=mitosis_begin2;
                mitosis_end=[mitosis_begin(2:end) a0(end)];
            else
                mitosis_end=a0(end);
            end
            
            num_mitosis_events = length(mitosis_begin);
            mitosis_begin2=[];
            mitosis_end2=[];
            for jj=1:length(mitosis_begin)
                mitosis_event=red_channel(mitosis_begin(jj):mitosis_end(jj));
                signal_drop=max(mitosis_event)-min(mitosis_event);
                if signal_drop>min_signal_drop
                    mitosis_begin2 = [mitosis_begin2 mitosis_begin(jj)];
                    mitosis_end2 = [mitosis_end2 mitosis_end(jj)];
                end
            end
            mitosis_begin=mitosis_begin2;
            mitosis_end=mitosis_end2;
            num_mitosis_events=length(mitosis_begin);
            else
                num_mitosis_events=0;
            end
            
            %find G1-S transition
            b0 = find(green_Slope<thresh_steep);
            c0 = find(red_Slope(b0)>0);
            %add function to count all G1-S transitions
            classified(b0(c0))=0.75;
            c00=find(diff(b0(c0))>1);
            if ~isempty(c0)
            G1_S_begin=[b0(c0(1)) b0(c0(c00+1))];
            G1_S_begin2=G1_S_begin(1);
                for ii=1:length(G1_S_begin)-1
                    if G1_S_begin(ii+1)-G1_S_begin(ii)>time_interval_mitosis
                        G1_S_begin2=[G1_S_begin2 G1_S_begin(ii+1)];
                    else
                        classified(G1_S_begin(ii):G1_S_begin(ii+1))=0.75;
                    end
                end
                G1_S_begin=G1_S_begin2;
            if length(G1_S_begin)>1
                G1_S_end=[mitosis_begin(2:end) b0(c0(end))];
            else
                G1_S_end=b0(c0(end));
            end
            num_G1_S_events=length(G1_S_begin);
            else
                num_G1_S_events=0;
            end
            % num_G1_S_events;
            % num_mitosis_events;
            if num_G1_S_events==0 & num_mitosis_events==0
                gr_mean=mean(green_channel);
                rd_mean=mean(red_channel(:));
                if gr_mean<rd_mean
                    classified=ones(size(classified))*0.75;
                else
                    classified=ones(size(classified))*0.5;
                end
            elseif num_G1_S_events==0 & num_mitosis_events==1
                classified(1:mitosis_begin-1)=0.75;
                classified(mitosis_end+1:end)=0.5;
            elseif num_G1_S_events==1 & num_mitosis_events==0
                classified(1:G1_S_begin-1)=0.5;
                classified(G1_S_end+1:end)=0.75;
            elseif num_G1_S_events==1 & num_mitosis_events==1
                if G1_S_begin<mitosis_begin
                    classified(1:G1_S_begin-1)=0.5;
                    classified(G1_S_end+1:mitosis_begin-1)=0.75;
                    classified(mitosis_end+1:end)=0.5;
                else
                    classified(1:mitosis_begin-1)=0.75;
                    classified(mitosis_end+1:G1_S_begin-1)=0.5;
                    classified(G1_S_end+1:end)=0.75;
                end
            end
            % split region 4 into 5 different sections
            a0=find(classified==0.75);
            if ~isempty(a0)
                a00=green_channel2(a0)>0;
                a0=a0(a00);
            end
            if ~isempty(a0)
            min4=(min(green_channel2(a0)));
            b0=find(green_channel2(a0)==min4);
            a0=a0(b0:end);
            max4=max(green_channel2(a0));
            thresh=min4+0.1*(max4-min4);
            G2_Region=find(green_channel2(a0)>thresh);
            if ~isempty(G2_Region)
            classified(a0(G2_Region(1):end))=1;
            end
            end
        end
        
        function classified = delete_begin_end(app, classified)
            % Function that deletes the begin and end of a certain 
            % classification. It puts a zero for deleted 
            % classification. (JWB)
            
            % Determine begin and end.
            first = classified(1);
            last = classified(end);
            
            % Loop over entries from second item
            for i=1:1:length(classified)
                if classified(i) ~= first
                    % A classification boundary is reached
                    break
                end
                classified(i) = 0;
            end
            
            % Loop backwards over entries from second to last item
            for i=length(classified):-1:1
                if classified(i) ~= last
                    % A classification boundary is reached
                    break
                end
                classified(i) = 0;
            end
        end
        
        function [green_channel, red_channel, white_channel, green_channel2, red_channel2, white_channel2] = get_channels(app, NUM, ii, current_cell)
            % Function to get the channels from the NUM part of the excel file
            aa=find(NUM(:,2)==ii);
            if ~isempty(aa)
                green_channel=NUM(aa,11);
                red_channel=NUM(aa,12);
                white_channel=NUM(aa,13);
            
                % Create normalized channels
                % green_channel2 = zeros(length(green_channel));
                % red_channel2   = zeros(length(green_channel));
                % white_channel2 = zeros(length(green_channel));
                for kk=2:length(green_channel)-1
                    if current_cell==1
                        green_channel2(kk)=max(green_channel(kk-1:kk+1));
                        red_channel2(kk)=max(red_channel(kk-1:kk+1));
                        white_channel2(kk)=max(white_channel(kk-1:kk+1));
                    else
                        green_channel2(kk)=min(green_channel(kk-1:kk+1));
                        red_channel2(kk)=min(red_channel(kk-1:kk+1));
                        white_channel2(kk)=min(white_channel(kk-1:kk+1));
                    end
                end
                green_channel2 = green_channel2/max(green_channel2);
                red_channel2   = red_channel2/max(red_channel2);
                white_channel2 = white_channel2/max(white_channel2);
                green_channel2 = medfilt1(green_channel2, 5);
                red_channel2   = medfilt1(red_channel2, 5);
                white_channel2 = medfilt1(white_channel2, 5);
            end
        end
        
        function [green_channel, red_channel, white_channel, green_channel2, red_channel2, white_channel2] = get_channels_an(app, annotated_data, current_track, current_cell)
            % Function that gets the channels from the annotated_data for a specific
            % track and a specific cell (JWB).
            a0=annotated_data(:,1)==current_track;
            aa=find(annotated_data(a0,2)==current_cell);
            if ~isempty(aa)
                green_channel=annotated_data(aa,3);
                red_channel=annotated_data(aa,4);
                white_channel=annotated_data(aa,5);
                for kk=2:length(green_channel)-1
                    if current_cell==1
                        green_channel2(kk)=max(green_channel(kk-1:kk+1));
                        red_channel2(kk)=max(red_channel(kk-1:kk+1));
                        white_channel2(kk)=max(white_channel(kk-1:kk+1));
                    else
                        green_channel2(kk)=min(green_channel(kk-1:kk+1));
                        red_channel2(kk)=min(red_channel(kk-1:kk+1));
                        white_channel2(kk)=min(white_channel(kk-1:kk+1));
                    end
                end
                green_channel2=green_channel2/max(green_channel2);
                red_channel2=red_channel2/max(red_channel2);
                white_channel2=white_channel2/max(white_channel2);
                green_channel2=medfilt1(green_channel2,5);
                red_channel2=medfilt1(red_channel2,5);
                white_channel2=medfilt1(white_channel2,5);
            end
        end
        
        function params = get_params(app, handles)
            % function that gets the edit parameters from edit boxes (JWB)
            params.thresh_gradual        = str2double(get(handles.edit1,'string'));
            params.thresh_steep          = str2double(get(handles.edit2,'string'));
            params.time_interval_mitosis = str2double(get(handles.edit3,'string'));
            params.time_min_mitosis      = str2double(get(handles.edit4,'string'));
            params.min_signal_drop       = str2double(get(handles.edit5,'string'));
        end
        
        function update_plots(app, handles)
            % The color intensity plot is updated through this function,
            % edit this function for style changes to the plot (JWB)
            
            % Set the correct axes
            axes(handles.axes1)
            
            % Plot the lines
            plot(handles.green_channel2,'g', 'LineWidth', 1.5)
            hold on
            plot(handles.red_channel2,'r', 'LineWidth', 1.5)
            plot(handles.white_channel2,'c', 'LineWidth', 1.5)
            plot(handles.classified,'k', 'LineWidth', 1.5)
            hold off
            
            % Legend and labels
            legend('PIP-mVenus','Gem-mCherry','SiR-DNA','Classification', 'Location', 'best')
            xlabel('Frame')
            ylabel('Relative intensity')
            
            % Grid settings, first off because of toggling minor grid.
            grid off
            grid on
            grid minor
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FUCCI_V4_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function sets the base values in all edit boxes of the FUCCI GUI.
            set(handles.edit1,'string','0.001')
            set(handles.edit2,'string','-0.05')
            set(handles.edit3,'string','20')
            set(handles.edit4,'string','3')
            set(handles.edit5,'string','5000')
            set(handles.listbox1,'string','1')
            set(handles.edit7,'string','1');
            set(handles.edit8,'string','10');
            set(handles.listbox2,'value',1);
            
            % Choose default command line output for FUCCI_V4
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Save_data
        function Save_data_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Function that saves the output in a .xlsx file (JWB)
            [filename, pathname] = uiputfile('*.xlsx','save file as..');
            
            % Jump back to GUI window
            figure(app.figure1)
            
            % Write the excel file
            annotated_data=handles.annotated_data;
            xlswrite([pathname filename], {'track #', 'cell #' , 'red channel' , 'green channel','white channel', 'cell cycle stage'}, 'sheet1' , 'A1')
            xlswrite([pathname filename], annotated_data, 'sheet1', 'A2');
            handles.annotated_data=annotated_data;
            guidata(hObject,handles);
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Callback function to 'listbox1' to pick 'cells?' (JWB)
            annotated_data=handles.annotated_data;
            current_cell=get(hObject,'value');
            current_track=handles.current_track;
            
            [~, ~, ~, green_channel2,red_channel2, white_channel2] = get_channels_an(app, handles.annotated_data,current_track,current_cell);
            a0=find(annotated_data(:,1)==current_track);
            b0=annotated_data(a0,2)==current_cell;
            classified=annotated_data(a0(b0),6);
            
            % Save into handles
            handles.classified=classified;
            handles.green_channel2 = green_channel2;
            handles.red_channel2   = red_channel2;
            handles.white_channel2 = white_channel2;
            handles.annotated_data = annotated_data;
            
            % Update plots
            update_plots(app, handles)
            
            guidata(hObject,handles);
        end

        % Menu selected function: open
        function open_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Function for the excel opening of raw data. (JWB)
            [filename,pathname]=uigetfile('*.xlsx');
            
            % Jump back to GUI window
            figure(app.figure1)
            
            % Read the excel file
            [NUM,TXT,RAW]=xlsread([pathname, filename]);
            handles.NUM=NUM;
            handles.TXT=TXT;
            handles.RAW=RAW;
            f0=~isnan(NUM(:,2));
            
            set(handles.popupmenu1,'string',num2str(unique(NUM(f0,2))));
            %annotata and tabulate data
            annotated_data=[];
            params=get_params(app, handles);
            list_track=get(handles.popupmenu1,'string');
            for ii=1:length(list_track)
                try
                    current_track=str2double(list_track(ii,:));
                    is_mitosis=check_for_mitosis(app, handles.NUM,current_track,handles);
                    for current_cell=0+1:is_mitosis+1
                        % Get channels
                        [green_channel, red_channel, white_channel, green_channel2,red_channel2, white_channel2]=get_channels(app, handles.NUM,current_track,current_cell);
            
                        % Classify stages
                        classified = classify_stages(app, params,red_channel,green_channel,white_channel,red_channel2,green_channel2,white_channel2);
            
                        % Set begin and end to zero
                        classified = delete_begin_end(app, classified);
            
                        % Annotate
                        annotated_data = [annotated_data ; ones(size(classified))'*current_track,...
                            ones(size(classified))'*current_cell, green_channel(1:length(classified)), ...
                            red_channel(1:length(classified)), white_channel(1:length(classified)), classified'];
                    end
                catch ME
                   disp([ 'the track is' num2str(current_track)])
                   pause
                end
            end
            
            % Save annotated data
            handles.annotated_data=annotated_data;
            guidata(hObject, handles);
        end

        % Menu selected function: open_classified
        function open_classified_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Function that opens the excel file for already classified data. (JWB)
            [filename,pathname]=uigetfile('*.xlsx');
            
            % Jump back to GUI window
            figure(app.figure1)
            
            % Read excel
            [NUM,TXT,RAW]=xlsread([pathname, filename]);
            handles.NUM=NUM;
            handles.TXT=TXT;
            handles.RAW=RAW;
            handles.annotated_data=NUM;
            set(handles.popupmenu1,'string',num2str(unique(NUM(:,1))));
            guidata(hObject,handles);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % The function that determines the callback from popupmenu1 (the selection
            % box for different cell tracks) (JWB)
            annotated_data=handles.annotated_data;
            V=get(hObject,'Value');
            tracks = get(hObject,'String');
            current_track = str2double(tracks(V,:));
            handles.current_track=current_track;
            % params = get_params(handles);
            % is_mitosis=check_for_mitosis_an(handles.annotated_data,current_track,handles);
            current_cell=1;
            [~, ~, ~, green_channel2,red_channel2, white_channel2] = get_channels_an(app, handles.annotated_data,current_track,current_cell);
            a0=find(annotated_data(:,1)==current_track);
            b0=find(annotated_data(a0,2)==current_cell);
            classified=annotated_data(a0(b0),6);
            
            % Save into handles
            handles.classified     = classified;
            handles.green_channel2 = green_channel2;
            handles.red_channel2   = red_channel2;
            handles.white_channel2 = white_channel2;
            handles.annotated_data = annotated_data;
            
            % Update plots
            update_plots(app, handles)
            
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Function that updates the plots and manual classification (JWB)
            annotated_data=handles.annotated_data;
            current_track=handles.current_track;
            current_cell=get(handles.listbox1,'value');
            
            % Get values from edit7 and edit8 + round (JWB)
            start_value = round(str2double(get(handles.edit7,'string')));
            end_value   = round(str2double(get(handles.edit8,'string')));
            
            % Get phase from listbox2 (JWB)
            cycle_phase = get(handles.listbox2,'value');
            
            % Change classification
            handles.classified(start_value:end_value) = app.phaseval(cycle_phase);
            
            % Update plots
            update_plots(app, handles)
            
            % update annotated_data
            a0=find(annotated_data(:,1)==current_track);
            b0=annotated_data(a0,2)==current_cell;
            annotated_data(a0(b0),6)=handles.classified;
            handles.annotated_data=annotated_data;
            guidata(hObject,handles);
        end

        % Callback function
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FUCCI_V5';
            app.figure1.Tag = 'figure1';
            app.File = uimenu(app.figure1);
            app.File.Text = 'Load data';
            app.File.Tag = 'File';
            app.open = uimenu(app.File);
            app.open.MenuSelectedFcn = createCallbackFcn(app, @open_Callback, true);
            app.open.Separator = 'on';
            app.open.Text = 'Raw data (.xlsx)';
            app.open.Tag = 'open';
            app.RawdatacsvMenu = uimenu(app.File);
            app.RawdatacsvMenu.Text = 'Raw data (.csv)';
            app.open_classified = uimenu(app.File);
            app.open_classified.MenuSelectedFcn = createCallbackFcn(app, @open_classified_Callback, true);
            app.open_classified.Text = 'Classified data';
            app.open_classified.Tag = 'open_classified';
            app.ImagefilesMenu = uimenu(app.File);
            app.ImagefilesMenu.Text = 'Image files';
            app.SavetofileMenu = uimenu(app.figure1);
            app.SavetofileMenu.Text = 'Save to file';
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {'Listbox'};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = 'Listbox';
            app.Save_data = uibutton(app.figure1, 'push');
            app.Save_data.ButtonPushedFcn = createCallbackFcn(app, @Save_data_Callback, true);
            app.Save_data.Tag = 'Save_data';
            app.Save_data.Text = 'Save to file';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Pop-up Menu'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Pop-up Menu';
            app.AutomaticcharacterisationsettingsPanel = uipanel(app.figure1);
            app.AutomaticcharacterisationsettingsPanel.Title = 'Automatic characterisation settings';
            app.GridLayout = uigridlayout(app.AutomaticcharacterisationsettingsPanel);
            app.edit1 = uieditfield(app.GridLayout, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = 'Edit Text';
            app.edit2 = uieditfield(app.GridLayout, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = 'Edit Text';
            app.edit3 = uieditfield(app.GridLayout, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = 'Edit Text';
            app.edit4 = uieditfield(app.GridLayout, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = 'Edit Text';
            app.edit5 = uieditfield(app.GridLayout, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Value = 'Edit Text';
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'gradual threshold';
            app.text3 = uilabel(app.GridLayout);
            app.text3.Tag = 'text3';
            app.text3.Text = 'steep threshold';
            app.text4 = uilabel(app.GridLayout);
            app.text4.Tag = 'text4';
            app.text4.Text = 'mitosis interval 1';
            app.text5 = uilabel(app.GridLayout);
            app.text5.Tag = 'text5';
            app.text5.Text = 'mitosis interval 2';
            app.text6 = uilabel(app.GridLayout);
            app.text6.Tag = 'text6';
            app.text6.Text = 'minimum signal drop';
            app.ManualcharacterisationupdaterPanel = uipanel(app.figure1);
            app.ManualcharacterisationupdaterPanel.Title = 'Manual characterisation updater';
            app.pushbutton4 = uibutton(app.ManualcharacterisationupdaterPanel, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Update';
            app.text8 = uilabel(app.ManualcharacterisationupdaterPanel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'end value';
            app.text7 = uilabel(app.ManualcharacterisationupdaterPanel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'start value';
            app.edit8 = uieditfield(app.ManualcharacterisationupdaterPanel, 'text');
            app.edit8.Tag = 'edit8';
            app.edit8.Value = 'Edit Text';
            app.edit7 = uieditfield(app.ManualcharacterisationupdaterPanel, 'text');
            app.edit7.Tag = 'edit7';
            app.edit7.Value = 'Edit Text';
            app.listbox2 = uilistbox(app.ManualcharacterisationupdaterPanel);
            app.listbox2.Items = {'Uncertain', 'M', 'G1', 'S', 'G2'};
            app.listbox2.Tag = 'listbox2';
            app.listbox2.Value = 'M';
            app.IntensityPlot = uiaxes(app.figure1);
            app.IntensityPlot.Tag = 'axes1';
            app.ImagePlot = uiaxes(app.figure1);
            app.ImagePlot.Tag = 'axes2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FUCCI_V5(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)FUCCI_V4_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end